classdef GeoTiff_viewer < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure                matlab.ui.Figure
		Menu_File               matlab.ui.container.Menu
		Menu_LoadGeoTiff        matlab.ui.container.Menu
		Menu_LoadLAS            matlab.ui.container.Menu
		GridLayout              matlab.ui.container.GridLayout
		LeftPanel               matlab.ui.container.Panel
		Spinner_Slicewidth      matlab.ui.control.Spinner
		WidthLabel              matlab.ui.control.Label
		Button_DefineSliceView  matlab.ui.control.Button
		Spinner_ClipData        matlab.ui.control.Spinner
		ClipdataLabel_2         matlab.ui.control.Label
		DropDown_ColorPallete   matlab.ui.control.DropDown
		ColormapLabel           matlab.ui.control.Label
		DropDown_SelectMetric   matlab.ui.control.DropDown
		MetricLabel             matlab.ui.control.Label
		CheckBox_ClipData       matlab.ui.control.CheckBox
		RightPanel              matlab.ui.container.Panel
		UIAxes_PlotTiff         matlab.ui.control.UIAxes
	end

	% Properties that correspond to apps with auto-reflow
	properties (Access = private)
		onePanelWidth = 576;
	end


	properties (Access = private)
		m_fileName
		m_metrics = []
		m_RR
		m_lidarNames = ["Hmax", "Hmean","Hmedian","Hp25","Hp75", "Hp95"...
			"PPR","DAM_z","BR_below_1","BR_1_2","BR_2_3","BR_above_3","BR_3_4","BR_4_5","BR_below_5","BR_5_20","BR_above_20" ...
			"Coeff_var_z", "Hkurt", "Hskew", "Hstd", "Hvar", "Shannon"]
		m_colorMap = []
		m_colorData = []
		m_ptCloud pointCloud
		m_ptAttributes lidarPointAttributes
	end

	methods (Access = private)

		function DrawGraph(app)
			[X, Y] = app.m_RR.worldGrid();

			value = app.DropDown_SelectMetric.Value;
			band = find(contains(app.m_lidarNames, value));
			cmap = app.DropDown_ColorPallete.Value;
			chosenRaster = app.m_metrics(:,:, band);
			clipPercentile = app.Spinner_ClipData.Value;

			if (app.CheckBox_ClipData.Value == 1)
				% compute chosen percentile for clipping
				chosenPercentile = prctile(chosenRaster(:), clipPercentile);

				% clip raster data according to the chosen percentile
				chosenRaster(chosenRaster > chosenPercentile) = chosenPercentile;
			end

			title(app.UIAxes_PlotTiff, app.m_fileName,'Interpreter','none')
			alphaData = ones(size(X));
			alphaData(isnan(chosenRaster)) = 0;
			imagesc(app.UIAxes_PlotTiff, X(1,:), Y(:,1), chosenRaster, 'AlphaData', alphaData)
			% 			surf(app.UIAxes_PlotTiff, X, Y, chosenRaster, 'AlphaData', alphaData,'EdgeAlpha', 0)
			daspect(app.UIAxes_PlotTiff, [1 1 1])
			xticks(app.UIAxes_PlotTiff,'auto')
			yticks(app.UIAxes_PlotTiff,'auto')
			set(app.UIAxes_PlotTiff,'YDir','normal') % spravne hodnoty na y osi
			colormap(app.UIAxes_PlotTiff, cmap)
			colorbar(app.UIAxes_PlotTiff)
			axis(app.UIAxes_PlotTiff, 'equal')
			% 			view(app.UIAxes_PlotTiff,3)

		end
	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			app.m_colorMap = load('las_colormap.txt')/255; % color map for classified point cloud
			app.m_colorMap(3, :) = app.m_colorMap(5,:);
			app.m_colorMap(4, :) = app.m_colorMap(5,:);

		end

		% Menu selected function: Menu_LoadGeoTiff
		function Menu_LoadGeoTiffSelected(app, event)
			[app.m_fileName, filePath] = uigetfile('*.tif', 'Select TIF file'); % .osm file path
			if isempty(filePath)
				return
			end
			filePath = fullfile(filePath, app.m_fileName);

			fig = uifigure;
			d = uiprogressdlg(fig, 'Title', 'Loading selected TIF file',...
				'Indeterminate','on');


			[app.m_metrics, app.m_RR] = readgeoraster(filePath, 'OutputType', 'double');
			infoMetrics = georasterinfo(filePath);

			app.m_metrics = standardizeMissing(app.m_metrics, infoMetrics.MissingDataIndicator);
			app.m_metrics = flipud(app.m_metrics);
			app.m_RR.ColumnsStartFrom = 'south';

			close(d)
			close(fig)

			app.DropDown_SelectMetric.Items = app.m_lidarNames;

			DrawGraph(app);

		end

		% Value changed function: DropDown_ColorPallete
		function DropDown_ColorPalleteValueChanged(app, event)
			if (isempty(app.m_metrics))
				return
			end

			DrawGraph(app);
		end

		% Value changed function: DropDown_SelectMetric
		function DropDown_SelectMetricValueChanged(app, event)
			if (isempty(app.m_metrics))
				return
			end

			DrawGraph(app);

		end

		% Value changed function: CheckBox_ClipData
		function CheckBox_ClipDataValueChanged(app, event)
			if (isempty(app.m_metrics))
				return
			end

			DrawGraph(app);
		end

		% Value changed function: Spinner_ClipData
		function Spinner_ClipDataValueChanged(app, event)
			if (isempty(app.m_metrics) || app.CheckBox_ClipData.Value == 0)
				return
			end

			DrawGraph(app);
		end

		% Button pushed function: Button_DefineSliceView
		function Button_DefineSliceViewPushed(app, event)
			if (isempty(app.m_ptCloud))
				return
			end

			DrawGraph(app)

			fhv = app.UIFigure.HandleVisibility;        % Current status
			app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
			set(0, 'CurrentFigure', app.UIFigure)       % Make fig current

			A = ginput(1);
			hold(app.UIAxes_PlotTiff,'on')
			plot(app.UIAxes_PlotTiff, A(1), A(2), 'ok','MarkerSize',10, 'LineWidth', 3)
			B = ginput(1);
			plot(app.UIAxes_PlotTiff, [A(1), B(1)], [A(2), B(2)], 'o-k', 'LineWidth', 3, 'MarkerSize',10)

			app.UIFigure.HandleVisibility = fhv;        % return original state

			drawnow
			hold(app.UIAxes_PlotTiff, 'off')

			s = B - A;
			a = -s(2);
			b =  s(1);
			c = - a*A(1) - b*A(2);
			d = @(X) abs(a*X(:,1) + b*X(:,2) + c)/sqrt(a*a + b*b);

			width = app.Spinner_Slicewidth.Value;

			xy = app.m_ptCloud.Location(:,1:2);
			t = (xy - A) / (B - A);
			selected = d(xy) < (width/2) & t <= 1 & t >= 0;
% 			classes = app.m_ptAttributes.Classification == 2;
			figure
			if any(selected)
				pcTemp = app.m_ptCloud.select(selected);
				
				pcshow(pcTemp.Location, app.m_colorData(selected, :), ...
					'MarkerSize', 20,'BackgroundColor','white')
			end

			axis on
			grid off
			set(gca,'xtick',[]);
			set(gca,'xcolor',[1 1 1])
			set(gca,'ytick',[]);
			set(gca,'ycolor',[1 1 1])
% 			set(gca,'zcolor',[1 1 1])

		end

		% Menu selected function: Menu_LoadLAS
		function Menu_LoadLASSelected(app, event)
			[lasFileName, lasFilePath] = uigetfile('*.las;*.laz', 'Select LAS/LAZ file'); % .osm file path
			lasFilePath = fullfile(lasFilePath, lasFileName);

			if isempty(lasFileName)
				reutrn
			end

			fig = uifigure;
			d = uiprogressdlg(fig, 'Title', 'Loading selected LAS file',...
				'Message','Reading meta-data');
			lasReader = lasFileReader(lasFilePath);
			d.Value = 0.5;
			d.Message = 'Reading point cloud data';
			[app.m_ptCloud, app.m_ptAttributes] = readPointCloud(lasReader, 'Attributes', 'Classification');
			app.m_colorData = reshape(label2rgb(app.m_ptAttributes.Classification, app.m_colorMap, 'k'), [], 3);
			d.Value = 1.0;
			close(d)
			close(fig)
		end

		% Changes arrangement of the app based on UIFigure width
		function updateAppLayout(app, event)
			currentFigureWidth = app.UIFigure.Position(3);
			if(currentFigureWidth <= app.onePanelWidth)
			    % Change to a 2x1 grid
			    app.GridLayout.RowHeight = {685, 685};
			    app.GridLayout.ColumnWidth = {'1x'};
			    app.RightPanel.Layout.Row = 2;
			    app.RightPanel.Layout.Column = 1;
			else
			    % Change to a 1x2 grid
			    app.GridLayout.RowHeight = {'1x'};
			    app.GridLayout.ColumnWidth = {220, '1x'};
			    app.RightPanel.Layout.Row = 1;
			    app.RightPanel.Layout.Column = 2;
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
			app.Menu_File = uimenu(app.UIFigure);
			app.Menu_File.Text = 'File';
			app.Menu_LoadGeoTiff = uimenu(app.Menu_File);
			app.Menu_LoadGeoTiff.MenuSelectedFcn = createCallbackFcn(app, @Menu_LoadGeoTiffSelected, true);
			app.Menu_LoadGeoTiff.Accelerator = 'o';
			app.Menu_LoadGeoTiff.Text = 'Load GeoTiff';
			app.Menu_LoadLAS = uimenu(app.Menu_File);
			app.Menu_LoadLAS.MenuSelectedFcn = createCallbackFcn(app, @Menu_LoadLASSelected, true);
			app.Menu_LoadLAS.Accelerator = 'L';
			app.Menu_LoadLAS.Text = 'Load LAS';
			app.GridLayout = uigridlayout(app.UIFigure);
			app.LeftPanel = uipanel(app.GridLayout);
			app.CheckBox_ClipData = uicheckbox(app.LeftPanel);
			app.CheckBox_ClipData.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ClipDataValueChanged, true);
			app.CheckBox_ClipData.Text = '';
			app.MetricLabel = uilabel(app.LeftPanel);
			app.MetricLabel.Text = 'Metric:';
			app.DropDown_SelectMetric = uidropdown(app.LeftPanel);
			app.DropDown_SelectMetric.Items = {};
			app.DropDown_SelectMetric.ValueChangedFcn = createCallbackFcn(app, @DropDown_SelectMetricValueChanged, true);
			app.DropDown_SelectMetric.Value = {};
			app.ColormapLabel = uilabel(app.LeftPanel);
			app.ColormapLabel.Text = 'Colormap:';
			app.DropDown_ColorPallete = uidropdown(app.LeftPanel);
			app.DropDown_ColorPallete.Items = {'parula', 'turbo', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'jet', 'lines', 'colorcube', 'prism', 'flag', 'white'};
			app.DropDown_ColorPallete.ValueChangedFcn = createCallbackFcn(app, @DropDown_ColorPalleteValueChanged, true);
			app.DropDown_ColorPallete.Value = 'jet';
			app.ClipdataLabel_2 = uilabel(app.LeftPanel);
			app.ClipdataLabel_2.Text = 'Clip data:';
			app.Spinner_ClipData = uispinner(app.LeftPanel);
			app.Spinner_ClipData.Step = 0.05;
			app.Spinner_ClipData.Limits = [0 100];
			app.Spinner_ClipData.ValueChangedFcn = createCallbackFcn(app, @Spinner_ClipDataValueChanged, true);
			app.Spinner_ClipData.Value = 97.5;
			app.Button_DefineSliceView = uibutton(app.LeftPanel, 'push');
			app.Button_DefineSliceView.ButtonPushedFcn = createCallbackFcn(app, @Button_DefineSliceViewPushed, true);
			app.Button_DefineSliceView.Text = 'Define slice';
			app.WidthLabel = uilabel(app.LeftPanel);
			app.WidthLabel.Text = 'Slice width:';
			app.Spinner_Slicewidth = uispinner(app.LeftPanel);
			app.Spinner_Slicewidth.Limits = [1 30];
			app.Spinner_Slicewidth.Value = 10;
			app.RightPanel = uipanel(app.GridLayout);
			app.UIAxes_PlotTiff = uiaxes(app.RightPanel);
			title(app.UIAxes_PlotTiff, 'Title')
			xlabel(app.UIAxes_PlotTiff, 'X')
			ylabel(app.UIAxes_PlotTiff, 'Y')
			zlabel(app.UIAxes_PlotTiff, 'Z')
			app.UIAxes_PlotTiff.XTick = [];
			app.UIAxes_PlotTiff.YTick = [];
			app.UIAxes_PlotTiff.ZTick = [];
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = GeoTiff_viewer

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end